classdef global_peaks_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NormalizeCountsButton           matlab.ui.control.StateButton
        ShowPeaksSwitch                 matlab.ui.control.Switch
        ShowRawSwitch                   matlab.ui.control.Switch
        ParameterDropDownLabel          matlab.ui.control.Label
        SmoothingDropDownLabel          matlab.ui.control.Label
        MethodDropDownLabel             matlab.ui.control.Label
        YcoordinateLabel                matlab.ui.control.Label
        XcoordinateSpinnerLabel         matlab.ui.control.Label
        SmthDegreeSpinnerLabel          matlab.ui.control.Label
        SmthSpanLabel                   matlab.ui.control.Label
        OfPeaksSpinnerLabel             matlab.ui.control.Label
        ThresholdLabel                  matlab.ui.control.Label
        MinDistEditFieldLabel           matlab.ui.control.Label
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ReflectionEditFieldLabel        matlab.ui.control.Label
        ShowRawRedLabel                 matlab.ui.control.Label
        SaveCurrentParameterDataButton  matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        UpperLimitEditField             matlab.ui.control.NumericEditField
        LowerLimitEditField             matlab.ui.control.NumericEditField
        ParameterDropDown               matlab.ui.control.DropDown
        PerformPeakFindingButton        matlab.ui.control.Button
        PeaksDropDown                   matlab.ui.control.DropDown
        PeaksDropDownLabel              matlab.ui.control.Label
        SmoothingDropDown               matlab.ui.control.DropDown
        MethodDropDown                  matlab.ui.control.DropDown
        SmthDegreeSpinner               matlab.ui.control.Spinner
        SmthSpanSpinner                 matlab.ui.control.Spinner
        OfPeaksSpinner                  matlab.ui.control.Spinner
        ThresholdEditField              matlab.ui.control.NumericEditField
        MinDistEditField                matlab.ui.control.NumericEditField
        ToleranceEditField              matlab.ui.control.NumericEditField
        ReflectionEditField             matlab.ui.control.NumericEditField
        Ycoordinate                     matlab.ui.control.Spinner
        Xcoordinate                     matlab.ui.control.Spinner
        LoadedListBox                   matlab.ui.control.ListBox
        ShowPeaksSwitchLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PeakFindingTab                  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PreviousSettingsButton          matlab.ui.control.StateButton
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        GenChunkButton                  matlab.ui.control.Button
        ofChunksEditField               matlab.ui.control.NumericEditField
        ofChunksEditFieldLabel          matlab.ui.control.Label
        UseSplittingButton              matlab.ui.control.StateButton
        LockLaplacianButton             matlab.ui.control.StateButton
        HighlightErrorsButton           matlab.ui.control.StateButton
        CurrentLaplacianThresholdsMHzpixelLabel  matlab.ui.control.Label
        LineCutDropDown                 matlab.ui.control.DropDown
        SpectrumAxes                    matlab.ui.control.UIAxes
        DisplayAxes                     matlab.ui.control.UIAxes
        PixelDetailsTab                 matlab.ui.container.Tab
        FileDataTable                   matlab.ui.control.Table
        PixelValueTable                 matlab.ui.control.Table
        PixelValueAxes                  matlab.ui.control.UIAxes
        SlopeSuppressionTab             matlab.ui.container.Tab
        PassDownSplitButton             matlab.ui.control.Button
        DisplayAlignmentSwitch          matlab.ui.control.Switch
        DisplayAlignmentSwitchLabel     matlab.ui.control.Label
        InterceptLabel                  matlab.ui.control.Label
        SlopeLabel                      matlab.ui.control.Label
        DifferencefromIdealSwitch       matlab.ui.control.Switch
        DifferencefromIdealSwitchLabel  matlab.ui.control.Label
        CalculateSlopesButton           matlab.ui.control.Button
        SavePlotDataButton              matlab.ui.control.Button
        FilestoUseListBoxLabel          matlab.ui.control.Label
        AlignImagesButton               matlab.ui.control.Button
        FilestoUseListBox               matlab.ui.control.ListBox
        PlottedValueDropDown            matlab.ui.control.DropDown
        PlottedValueDropDownLabel       matlab.ui.control.Label
        SlopeFileDataTable              matlab.ui.control.Table
        SlopeAxes                       matlab.ui.control.UIAxes
        SlopeMapAxes                    matlab.ui.control.UIAxes
        FunPlotsTab                     matlab.ui.container.Tab
        ColormapEditorButton            matlab.ui.control.Button
        LaplacianAxes                   matlab.ui.control.UIAxes
        VerticalSpectrumAxes            matlab.ui.control.UIAxes
        HorizontalSpectrumAxes          matlab.ui.control.UIAxes
        Axes3D                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        currentRandomIndex = 1
        randomCoords = [[1, 1]]
        xLine
        yLine
        xLine2
        yLine2
        parameter_labels = ["D_{111} (Hz)", "E_{111} (Hz)", "Width (σ)", ... 
                        "111 Width Deviation (σ)", "111 Contrast (%)", ... 
                        "111 Contrast Deviation", "D_{non111} (Hz)", ... 
                        "E_{non111}", "Non-111 Width (σ)", ...
                        "Non-111 Width Deviation", "Non-111 Contrast (%)", ...
                        "Non-111 Contrast Deviation", 'Baseline (Hz)', ...
                        'Tries', 'Left Peak', 'Right Peak', 'Laplacian'];
        all_gWides = struct("signal", {}, "SweepParam", {})
        all_parameter_dfs = {}
        all_reflection_dfs = {}
        % Create new all_settings struct when file loaded in with current
        % settings, whenever peak find run save the settings for that file 
        % and toggle in between them, much like all_reflection_dfs
        % Should I display them somewhere for reference? nahhhh its fine...
        all_settings = struct("reflection", {}, "tolerance", {}, ...
             "diff_peak_distance", {}, "peak_perc_threshold", {}, ...
             "num_max_peaks", {}, "smooth_span", {}, ...
            "smooth_degree", {}, "method", {}, "seed_method", {})
        x_coords
        slope_df
        lock_laplacian = 0
    end
    
    properties (Access = public)
        xcoord % Description
        ycoord
    end
    
    methods (Access = private)
        function plot_data(app)
           global gWide
           cla(app.DisplayAxes);
           data=squeeze(gWide.signal(:,:,2))';
           if app.NormalizeCountsButton.Value
               for x=1:size(data, 2)
                   for y=1:size(data, 1)
                       raw = squeeze(gWide.signal(x, y, :));
                       raw=raw-mean(raw);
                       raw=raw/std(raw);
                       data(y, x) = raw(2);
                   end
               end
           end
           im = imagesc(app.DisplayAxes, data); % Display the slice
           im.HitTest = 'off';
           colorbar(app.DisplayAxes);
           clim(app.DisplayAxes, 'auto')
           lims = clim(app.DisplayAxes);
           app.LowerLimitEditField.Value = lims(1);
           app.UpperLimitEditField.Value = lims(2);
           xlim(app.DisplayAxes,[1 size(data,2)])
           ylim(app.DisplayAxes,[1 size(data,1)])
           pbaspect(app.DisplayAxes,[1 size(data,1)/size(data,2) 1]);
           title(app.DisplayAxes, "Initial Signal Value")
            
           app.Xcoordinate.LowerLimitInclusive = 1;
           app.Ycoordinate.LowerLimitInclusive = 1;
           app.Xcoordinate.UpperLimitInclusive = size(data, 2);
           app.Ycoordinate.UpperLimitInclusive = size(data, 1);
           plot_signal(app);
        end

        function plot_signal(app, xin, yin)
            global gWide parameters_dataframe;
            % Get data from gWides
            if isempty(gWide)
                return;
            end
            data=squeeze(gWide.signal(:,:,2))';

            % Should be able to peak find and display
            if nargin < 2
               x = app.Xcoordinate.Value;
               y = app.Ycoordinate.Value;
               xin = x;
               yin = y;
            else
                x = xin;
                y = yin;
            end
            if xin > size(data, 2)
                x = size(data, 2) - 1;
            end
            if yin > size(data, 2)
                y = size(data, 2) - 1;
            end

            % If taking line cut make the x and ys arrays
            if app.LineCutDropDown.ValueIndex == 2 % Vertical = fixed x
                y = 1:size(data, 2) - 1;
            elseif app.LineCutDropDown.ValueIndex == 3 % horizontal = fixed y
                x = 1:size(data, 1) - 1;
            end
            
            data = gWide.signal;
            freq = gWide.SweepParam;
            smooth_span = app.SmthSpanSpinner.Value;
            smooth_degree = app.SmthDegreeSpinner.Value;
            % Plot the signal on the spectrum axes
            cla(app.SpectrumAxes);
            hold(app.SpectrumAxes, 'on');
            switch app.LineCutDropDown.Value
                case "Pixel"
                    raw = squeeze(data(x, y, :));
                    if app.NormalizeCountsButton.Value
                        raw=raw-mean(raw);
                        raw=raw/std(raw);
                    end
                    signal = smooth(raw, smooth_span, 'sgolay', smooth_degree);
                    lims = signal;
                    if strcmp(app.ShowRawSwitch.Value, "On")
                        plot(app.SpectrumAxes, freq, raw, 'Color', 'r')
                        if min(raw) < min(signal) && max(raw) > max(signal) 
                            lims = raw;
                        end
                    end
                    plot(app.SpectrumAxes, freq, signal, 'Color', 'b')
                    
                    if min(lims)==max(lims)
                        ylim(app.SpectrumAxes,[min(lims)-1 max(lims)+1])
                    else
                        ylim(app.SpectrumAxes, [min(lims), max(lims)])
                    end
                    app.SpectrumAxes.YDir = 'normal';
                    ylabel(app.SpectrumAxes,'Counts')
                    
                case "Vertical"
                    raw=squeeze(data(x,:,:));
                    % Normalize data
                    for i=1:size(raw,1)
                        raw(i,:)=raw(i,:)-mean(raw(i,:));
                        raw(i,:)=raw(i,:)/std(raw(i,:));
                    end
                    if app.ShowRawSwitch.ValueIndex ~= 2 % raw is off => smooth
                        for ysmooth=1:size(raw, 1)
                            raw(ysmooth, :) = smooth(raw(ysmooth, :), smooth_span, 'sgolay', smooth_degree);
                        end
                    end
                    imagesc(app.SpectrumAxes, freq, 1:size(raw,1),raw,[min(raw(:)) max(raw(:))]);
                    yline(app.SpectrumAxes, yin);
                    ylim(app.SpectrumAxes,[1 size(raw,1)]);
                    ylabel(app.SpectrumAxes,'Pixel');
                    app.SpectrumAxes.YDir = 'reverse';

                case "Horizontal"
                    raw=squeeze(data(:,y,:));
                    % Normalize data
                    for i=1:size(raw,1)
                        raw(i,:)=raw(i,:)-mean(raw(i,:));
                        raw(i,:)=raw(i,:)/std(raw(i,:));
                    end
                    if app.ShowRawSwitch.ValueIndex ~= 2 % raw is off => smooth
                        for xsmooth=1:size(raw, 1)
                            raw(xsmooth, :) = smooth(raw(xsmooth, :), smooth_span, 'sgolay', smooth_degree);
                        end
                    end
                    imagesc(app.SpectrumAxes, freq, 1:size(raw,1),raw,[min(raw(:)) max(raw(:))]);
                    yline(app.SpectrumAxes, xin);
                    ylim(app.SpectrumAxes,[1 size(raw,1)]);
                    ylabel(app.SpectrumAxes,'Pixel');
                    app.SpectrumAxes.YDir = 'reverse';
            end
            xlim(app.SpectrumAxes, [min(freq), max(freq)])

            if app.ShowPeaksSwitch.ValueIndex == 2
                % Get necessary parameters
                split = app.ReflectionEditField.Value;
                tolerance = app.ToleranceEditField.Value;
                num_max_peaks = app.OfPeaksSpinner.Value;
                peak_perc_threshold = app.ThresholdEditField.Value;
                diff_peak_distance = app.MinDistEditField.Value;

                % Find peaks
                found_peaks_info = struct("seed", {}, "seed2", {}, "threshold", {}, "index_pairs", {}, ...
                    "sorted_locs", {}, "sorted_vals", {}, "centers", {}, "locs", {}, "vals", {});
                fields = {'seed', 'seed2', 'threshold', 'index_pairs', 'sorted_locs', ...
                    'sorted_vals', 'centers', 'locs', 'vals'};

                data = gWide.signal;
                for xval = x
                    for yval = y
                        [seed, threshold, index_pairs, sorted_locs, sorted_vals, centers, locs, vals] = ...
                            peak_find_function(app.UseSplittingButton.Value, ...
                                split, tolerance, xval, yval, data, freq, ...
                                num_max_peaks, peak_perc_threshold, diff_peak_distance, ...
                                smooth_span, smooth_degree, app.MethodDropDown.ValueIndex, ...
                                app.PeaksDropDown.ValueIndex, app.NormalizeCountsButton.Value);
                
                        % Store the return values into a cell array
                        seed2 = 'none'; % will return error if not needed
                        if app.PreviousSettingsButton.Value
                            if ~isempty(parameters_dataframe)
                                seed2 = parameters_dataframe(yval, xval, :);
                            end
                        end
                        output = {seed, seed2, threshold, index_pairs, sorted_locs, sorted_vals, centers, locs, vals};
                        new_entry = cell2struct(num2cell(output), fields, 2);
                        found_peaks_info(end + 1) = new_entry;
                    end
                end
    
                % Plot the peaks
                switch app.LineCutDropDown.Value
                    case "Pixel"
                        yline(app.SpectrumAxes, found_peaks_info.threshold{1}, 'Color', 'r')
                        plot(app.SpectrumAxes, found_peaks_info.locs{1}, found_peaks_info.vals{1}, 'rv', 'MarkerFaceColor', 'k');
                        
                        colors = ['r', 'g', 'b', 'c', 'm'];
        
                        index_pairs = found_peaks_info.index_pairs{1};
                        sorted_locs = found_peaks_info.sorted_locs{1};
                        sorted_vals = found_peaks_info.sorted_vals{1};
                        seed = found_peaks_info.seed{1};
                        seed2 = found_peaks_info.seed2{1};
                        centers = found_peaks_info.centers{1};
                        if isempty(index_pairs)
                            if ~app.UseSplittingButton.Value
                                xline(app.SpectrumAxes, split, 'LineWidth', 2) % Provided split
                            end
                        end
                        shown_partners = zeros(numel(sorted_locs), 1);

                        for i = 1:size(index_pairs, 1) % For each pair
                            if shown_partners(index_pairs(i, 1)) == 0 && ...
                               shown_partners(index_pairs(i, 2)) == 0 % if we haven't shown the current peak's partner yet
                                shown_partners(index_pairs(i, 1)) = 1;
                                shown_partners(index_pairs(i, 2)) = 1;
                                peak = sorted_locs(index_pairs(i, 1)); % Get the first peak and second peak
                                peak_partner = sorted_locs(index_pairs(i, 2));
                                init_height = sorted_vals(index_pairs(i, 1));
                                second_height = sorted_vals(index_pairs(i, 2));
                                color = colors(mod(i - 1, numel(colors)) + 1); % Give them a color
                                plot(app.SpectrumAxes, peak, init_height, 'rv', 'MarkerFaceColor', color)
                                plot(app.SpectrumAxes, peak_partner, second_height, 'rv', 'MarkerFaceColor', color)
                                text(app.SpectrumAxes, peak_partner + 0.01e9, second_height, num2str(i))
                                text(app.SpectrumAxes, peak + 0.01e9,init_height,num2str(i))
        
                                if app.UseSplittingButton.Value
                                    bound = peak + split;
                                    if bound + split > max(freq)
                                        bound = peak - split;
                                    end
                                    xline(app.SpectrumAxes, bound, 'LineWidth', 2, 'Color', color) % In practice split
                                else
                                    xline(app.SpectrumAxes, split, 'LineWidth', 2) % Provided split
                                end
        
                                if app.PreviousSettingsButton.Value
                                    if ~isempty(app.all_parameter_dfs)
                                        if numel(app.all_parameter_dfs) >= app.LoadedListBox.ValueIndex % If parameters calculated
                                            if ~isempty(app.all_parameter_dfs{app.LoadedListBox.ValueIndex})
                                                prev_reflection = app.all_reflection_dfs{app.LoadedListBox.ValueIndex};
                                                prev_reflection = prev_reflection(y, x);
                                                xline(app.SpectrumAxes, prev_reflection, 'LineWidth', 2, 'Color', 'b') % previous split
                                            end
                                        end
                                    end
                                end
        
                                if peak == seed(15) || peak == seed(16) % If the peak is the current 111 peak, it should be drawn out
                                    center = centers(i);
                                    xline(app.SpectrumAxes, peak)
                                    xline(app.SpectrumAxes, center)
                                    xregion(app.SpectrumAxes, center - tolerance, center + tolerance, 'FaceColor', color)
                                end
                            end
                        end
                        % Color the past 111 peaks yellow if the user wants
                        % to see them
                        if app.PreviousSettingsButton.Value
                            plot(app.SpectrumAxes, seed2(15), seed2(13) * (seed2(5) - seed2(6) + 1), 'rv', 'MarkerFaceColor', 'y');
                            plot(app.SpectrumAxes, seed2(16), seed2(13) * (seed2(5) + seed2(6) + 1), 'rv', 'MarkerFaceColor', 'y');
                        end
                    case "Horizontal"
                        left_peak = zeros(numel(found_peaks_info), 1);
                        right_peak = zeros(numel(found_peaks_info), 1);
                        left_peak2 = zeros(numel(found_peaks_info), 1);
                        right_peak2 = zeros(numel(found_peaks_info), 1);
                        maxLength = max(cellfun(@length, {found_peaks_info.locs}));
                        all_peaks = nan(length(found_peaks_info), maxLength);

                        for i=1:numel(found_peaks_info)
                            seed = found_peaks_info(1, i).seed;
                            left_peak(i) = seed{1}(15);
                            right_peak(i) = seed{1}(16);
                            locs = found_peaks_info(1, i).locs{:};
                            all_peaks(i, 1:length(locs)) = locs;
                        end
                        if app.PreviousSettingsButton.Value 
                            for i=1:numel(found_peaks_info)
                                seed2 = found_peaks_info(1, i).seed2;
                                left_peak2(i) = seed2{1}(15);
                                right_peak2(i) = seed2{1}(16);
                            end
                        end
                        [xsize, ysize] = size(all_peaks);
                        for i=1:ysize
                            scatter(app.SpectrumAxes, all_peaks(:, i), 1:xsize, 1, 'k', 'filled');
                        end

                        plot(app.SpectrumAxes, left_peak, 1:numel(left_peak), right_peak, 1:numel(right_peak), 'Color', 'r')
                        plot(app.SpectrumAxes, left_peak2, 1:numel(left_peak2), right_peak2, 1:numel(right_peak2), 'Color', [1 1 1])
                    case "Vertical"
                        left_peak = zeros(numel(found_peaks_info), 1);
                        right_peak = zeros(numel(found_peaks_info), 1);
                        left_peak2 = zeros(numel(found_peaks_info), 1);
                        right_peak2 = zeros(numel(found_peaks_info), 1);
                        maxLength = max(cellfun(@length, {found_peaks_info.locs}));
                        all_peaks = nan(length(found_peaks_info), maxLength);

                        for i=1:numel(found_peaks_info)
                            seed = found_peaks_info(1, i).seed;
                            left_peak(i) = seed{1}(15);
                            right_peak(i) = seed{1}(16);
                            locs = found_peaks_info(1, i).locs{:};
                            all_peaks(i, 1:length(locs)) = locs;
                        end
                        if app.PreviousSettingsButton.Value 
                            for i=1:numel(found_peaks_info)
                                seed2 = found_peaks_info(1, i).seed2;
                                left_peak2(i) = seed2{1}(15);
                                right_peak2(i) = seed2{1}(16);
                            end
                        end
                        [xsize, ysize] = size(all_peaks);
                        for i=1:ysize
                            scatter(app.SpectrumAxes, all_peaks(:, i), 1:xsize, 1, 'k', 'filled');
                        end

                        plot(app.SpectrumAxes, left_peak, 1:numel(left_peak), right_peak, 1:numel(right_peak), 'Color', 'r')
                        plot(app.SpectrumAxes, left_peak2, 1:numel(left_peak2), right_peak2, 1:numel(right_peak2), 'Color', [1 1 1])
                end
           end
        
           hold(app.SpectrumAxes, 'off');
           table_signal(app);
           spectrum(app);
        end

        function table_signal(app)
            global parameters_dataframe
            x = app.Xcoordinate.Value;
            y = app.Ycoordinate.Value;

            index = app.ParameterDropDown.ValueIndex;
            if index > length(app.parameter_labels)
                index = 1;
            end
            if ~isempty(app.all_parameter_dfs)
                if x > size(parameters_dataframe(:, :, 2), 2)
                    x = size(parameters_dataframe(:, :, 2), 2) - 1;
                end
                if y > size(parameters_dataframe(:, :, 2), 2)
                    y = size(parameters_dataframe(:, :, 2), 2) - 1;
                end
                data = squeeze(parameters_dataframe(y, x, :));
                app.PixelValueTable.Data = [app.parameter_labels', data];
    
                all_data = zeros(size(app.all_parameter_dfs));
                for i=1:numel(app.all_parameter_dfs)
                    curr_param = app.all_parameter_dfs{i};
                    if ~isempty(curr_param)
                        all_data(i) = squeeze(curr_param(y, x, index));
                    end
                end

                if ~isempty(all_data)
                    file_names = 1:size(all_data, 2);
                    sample_x_coords = 1:size(all_data, 2);
                    app.x_coords = sample_x_coords';
                    if ~isempty(app.SlopeFileDataTable.Data)
                        if size(app.x_coords, 1) == size(app.SlopeFileDataTable.Data(:, 3), 1)
                            app.x_coords = app.SlopeFileDataTable.Data(:, 3);
                        end
                    end
                    
                    app.FileDataTable.Data = [file_names', all_data', app.x_coords];
                    app.SlopeFileDataTable.Data = [file_names', all_data', app.x_coords];
                    cla(app.PixelValueAxes)
                    hold(app.PixelValueAxes, 'on');
                    plot(app.PixelValueAxes, app.x_coords, all_data);
                    plot(app.PixelValueAxes, app.x_coords, all_data, 'rv');
                    hold(app.PixelValueAxes, 'off');

                    cla(app.SlopeAxes)
                    hold(app.SlopeAxes, 'on');
                    plot(app.SlopeAxes, app.x_coords, all_data, 'DisplayName', 'Actual');
                    plot(app.SlopeAxes, app.x_coords, all_data, 'rs', 'DisplayName', 'Actual Data');
                    if ~isempty(app.slope_df)
                        slope = squeeze(app.slope_df(y, x, 1));
                        intercept = squeeze(app.slope_df(y, x, 3));
                        equation = intercept + slope * app.x_coords;
                        plot(app.SlopeAxes, app.x_coords, equation, 'DisplayName', 'Fit')
                        plot(app.SlopeAxes, app.x_coords, equation, 'rv', 'DisplayName', 'Fit Data');
                        app.SlopeLabel.Text = "Slope: " + slope;
                        app.InterceptLabel.Text = "Intercept: " + intercept;

                        if app.DifferencefromIdealSwitch.ValueIndex == 2
                            ideal_slope = 28.025 * 1e5 * 24.11;
                            equation = intercept + ideal_slope * app.x_coords;
                            plot(app.SlopeAxes, app.x_coords, equation, 'DisplayName', 'Ideal');
                            plot(app.SlopeAxes, app.x_coords, equation, 'ro', 'DisplayName', 'Ideal Data');
                            app.SlopeLabel.Text = "Slope: " + slope + " vs. " + ideal_slope;
                        end
                    end
                    legend(app.SlopeAxes);
                    hold(app.SlopeAxes, 'off');
                end
            end
        end

        function surface3d(app)
            global parameters_dataframe
            i = app.ParameterDropDown.ValueIndex;
            if i > numel(app.parameter_labels)
                i = 1;
            end
            data = parameters_dataframe(:, :, i);
            data(data == 0) = NaN;
            surf(app.Axes3D, data);

            laplacian = del2(data);
            imagesc(app.LaplacianAxes, laplacian);
            colorbar(app.LaplacianAxes);
            clim(app.LaplacianAxes, 'auto');
            pbaspect(app.LaplacianAxes,[1 size(data,1)/size(data,2) 1]);
            xlim(app.LaplacianAxes,[1 size(data,2)])
            ylim(app.LaplacianAxes,[1 size(data,1)])
        end

        function spectrum(app)
            global parameters_dataframe
            if isempty(parameters_dataframe)
                return;
            end
            x = app.Xcoordinate.Value;
            y = app.Ycoordinate.Value;

            if x > size(parameters_dataframe(:, :, 2), 2)
                x = size(parameters_dataframe(:, :, 2), 2) - 1;
            end
            if y > size(parameters_dataframe(:, :, 2), 2)
                y = size(parameters_dataframe(:, :, 2), 2) - 1;
            end

            cla(app.VerticalSpectrumAxes);
            xData1 = parameters_dataframe(:, x, 15);
            xData1(xData1 == 0) = NaN; % don't show it if it's 0
            hold(app.VerticalSpectrumAxes, 'on')
            plot(app.VerticalSpectrumAxes, xData1, 'Color', 'b');
            xData2 = parameters_dataframe(:, x, 16);
            xData2(xData2 == 0) = NaN; % don't show it if it's 0
            plot(app.VerticalSpectrumAxes, xData2, 'Color','r');
            xline(app.VerticalSpectrumAxes, y);
            hold(app.VerticalSpectrumAxes, 'off')

            cla(app.HorizontalSpectrumAxes);
            xData1 = parameters_dataframe(y, :, 15);
            xData1(xData1 == 0) = NaN; % don't show it if it's 0
            hold(app.HorizontalSpectrumAxes, 'on')
            plot(app.HorizontalSpectrumAxes, xData1, 'Color', 'b');
            xData2 = parameters_dataframe(y, :, 16);
            xData2(xData2 == 0) = NaN; % don't show it if it's 0
            plot(app.HorizontalSpectrumAxes, xData2, 'Color','r');
            xline(app.HorizontalSpectrumAxes, x);
            hold(app.HorizontalSpectrumAxes, 'off')
        end

        function align_image_setup(app)
            if ~isempty(app.all_parameter_dfs)
                empty_indices = cellfun(@isempty, app.all_parameter_dfs);
                items = app.LoadedListBox.Items(~empty_indices);
                app.FilestoUseListBox.Items = items;
            end
        end

        function plot_parameter(app, i, parameters_dataframe)
            data = parameters_dataframe(:, :, i);
            data(data == 0) = NaN;
            set(app.DisplayAxes,'color', [1 0 0]);
            if app.HighlightErrorsButton.Value
                im = imagesc(app.DisplayAxes, data, 'AlphaData', 1 - parameters_dataframe(:, :, 17) * 0.5); % Display the slice
            else
                im = imagesc(app.DisplayAxes, data, 'AlphaData', ~isnan(data)); % Display the slice
            end
            im.HitTest = 'off';
            clim(app.DisplayAxes, "auto");
            colorbar(app.DisplayAxes); % Add a colorbar to each subplot
            lims = clim(app.DisplayAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            hold(app.DisplayAxes, 'on');
            title(app.DisplayAxes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(app.DisplayAxes, 'off')
        end
        
        function replot_crosshair(app, x, y)
            % Clear crosshairs
            if ~isempty(app.xLine)
                delete(app.xLine)
            end
            if ~isempty(app.yLine)
                delete(app.yLine)
            end
            if ~isempty(app.xLine2)
                delete(app.xLine2)
            end
            if ~isempty(app.yLine2)
                delete(app.yLine2)
            end

            if strcmp(app.ParameterDropDown.Value, "Tries") || strcmp(app.ParameterDropDown.Value, "Laplacian")
                app.xLine = xline(app.DisplayAxes, x, 'Color','c');
                app.yLine = yline(app.DisplayAxes, y, 'Color', 'c');
            else
                app.xLine = xline(app.DisplayAxes, x, 'Color','r');
                app.yLine = yline(app.DisplayAxes, y, 'Color', 'r');
            end
            app.xLine2 = xline(app.SlopeMapAxes, x, 'Color','r');
            app.yLine2 = yline(app.SlopeMapAxes, y, 'Color', 'r');
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
           global gWide
           [baseFileNames, folders] = uigetfile('.mat','Choose File', 'MultiSelect','on');
           figure(app.UIFigure)
            
           if isequal(baseFileNames, 0)
               % User clicked cancel
               return;
           end
            
           % Ensure baseFileNames is a cell array for consistent processing
           if ischar(baseFileNames)
               baseFileNames = {baseFileNames};
           end

           f = waitbar(0, "Loading...");
           for i = 1:numel(baseFileNames)
               baseFileName = baseFileNames{i};
               out=regexp(folders,'\','split');
               folder = out(end - 1) + "\";
               fullFileName = fullfile(folders, baseFileName);
               halfFileName = folder + baseFileName;
               halfFileName = {char(halfFileName)};
               loadedData = load(fullFileName);
               try
                   app.all_gWides(end + 1).signal = loadedData.gWide.signal;
               catch
                   uialert(app.UIFigure, "No gWide available in file " + baseFileName + ", load failed", "Error")
                   continue
               end
               app.all_gWides(end).SweepParam = loadedData.gWide.SweepParam;
               gWide = app.all_gWides(end);
               app.LoadedListBox.Items(end + 1) = halfFileName;
               app.LoadedListBox.Value = halfFileName;
               waitbar(i / numel(baseFileNames), f, "Loading...")
           end
           waitbar(i / numel(baseFileNames), f, "Completed!")
           close(f)
           plot_data(app);
           app.ParameterDropDown.Enable = 'off';
           app.ParameterDropDownLabel.Enable = 'off';
           figure(app.UIFigure)
        end

        % Value changed function: ShowPeaksSwitch
        function ShowPeaksSwitchValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ShowRawSwitch
        function ShowRawSwitchValueChanged(app, event)
            plot_signal(app);
        end

        % Button pushed function: GenChunkButton
        function GenChunkButtonPushed(app, event)
            global gWide
            app.currentRandomIndex = 1;
            data=squeeze(gWide.signal(:,:,2))';
            xsize = size(data, 2);
            ysize = size(data, 1);
            chunk_size = floor(xsize / app.ofChunksEditField.Value);
            app.randomCoords = [];
            for x = randi([0, chunk_size - 1], 1, ceil(xsize / chunk_size)) + (1:chunk_size:xsize)
                for y = randi([0, chunk_size - 1], 1, ceil(ysize / chunk_size)) + (1:chunk_size:ysize)
                    app.randomCoords(end + 1, :) = [x, y];
                end
            end

            app.Xcoordinate.Value = app.randomCoords(app.currentRandomIndex, 1);
            app.Ycoordinate.Value = app.randomCoords(app.currentRandomIndex, 2);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.currentRandomIndex = app.currentRandomIndex + 1;
            if app.currentRandomIndex > length(app.randomCoords)
                app.currentRandomIndex = 1;
            end
            app.Xcoordinate.Value = app.randomCoords(app.currentRandomIndex, 1);
            app.Ycoordinate.Value = app.randomCoords(app.currentRandomIndex, 2);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Button pushed function: PerformPeakFindingButton
        function PerformPeakFindingButtonPushed(app, event)
            global gWide parameters_dataframe
            % profile on;
            f = waitbar(0, "Initializing");
            data=squeeze(gWide.signal(:,:,2))';
            split = app.ReflectionEditField.Value;
            tolerance = app.ToleranceEditField.Value;
            xsize = size(data, 2);
            ysize = size(data, 1);
            num_params = numel(app.parameter_labels) - 1;
            parameters_dataframe = zeros(ysize, xsize, num_params);
            app.PerformPeakFindingButton.Enable = 'off';
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDownLabel.Enable = 'off';
            drawnow

            use_splitting = app.UseSplittingButton.Value;
            num_max_peaks = app.OfPeaksSpinner.Value;
            peak_perc_threshold = app.ThresholdEditField.Value;
            diff_peak_distance = app.MinDistEditField.Value;
            smooth_span = app.SmthSpanSpinner.Value;
            smooth_degree = app.SmthDegreeSpinner.Value;

            % mpiprofile on;
            data = gWide.signal;
            freq = gWide.SweepParam;
            method = app.MethodDropDown.ValueIndex;
            seed_method = app.PeaksDropDown.ValueIndex;
            normalize = app.NormalizeCountsButton.Value;
            pdf = parameters_dataframe;
            waitbar(0.33, f, "Finding Peaks");
            parfor x = 1:xsize
                for y = 1:ysize
                    pdf(y, x, :) = peak_find_function(use_splitting, split, ...
                        tolerance, x, y, data, freq, num_max_peaks, peak_perc_threshold, ...
                        diff_peak_distance, smooth_span, smooth_degree, method, seed_method, normalize);
                end
            end
            waitbar(0.66, f, "Smoothing");
            parameters_dataframe = pdf;
            parameters_dataframe = smoothing(parameters_dataframe, app.SmoothingDropDown.ValueIndex);
            if app.LockLaplacianButton.Value
                [pdf, ~] = laplacian_smoothing(parameters_dataframe, app.lock_laplacian, false);
            else
                [pdf, lock] = laplacian_smoothing(parameters_dataframe, 0, false);
                app.lock_laplacian = lock;
            end
            parameters_dataframe = pdf;
            if app.SmoothingDropDown.ValueIndex == 2
                % Repairs should use the old value if we're locked and
                % make new ones if we're not
                if app.LockLaplacianButton.Value
                    lock = app.lock_laplacian;
                else
                    lock = 0;
                end
                [pdf, thresh] = laplacian_repair(app.UseSplittingButton.Value, lock, ...
                    parameters_dataframe, data, freq, split, tolerance, ...
                    num_max_peaks, peak_perc_threshold, diff_peak_distance, ...
                    smooth_span, smooth_degree, app.PeaksDropDown.ValueIndex, false);
                parameters_dataframe = pdf;
                app.lock_laplacian = thresh;
            end
            app.CurrentLaplacianThresholdsMHzpixelLabel.Text = ['Current Laplacian Thresholds (MHz/pixel): ' sprintf('%g ', app.lock_laplacian)];

            if app.UseSplittingButton.Value
                app.all_reflection_dfs(app.LoadedListBox.ValueIndex) = {parameters_dataframe(:, :, 1)};
            else
                app.all_reflection_dfs{app.LoadedListBox.ValueIndex} = split * ones(ysize, xsize);
            end

            % for x=1:xsize
            %     for y=1:ysize
            %         d = squeeze(data(x, y, :));
            %         baseline = find_baseline(y, x, data, freq);
            %         parameters_dataframe(y, x, 17) = (min(d) - baseline) / baseline;
            %     end
            % end

            % mpiprofile viewer;
            app.all_parameter_dfs{app.LoadedListBox.ValueIndex} = parameters_dataframe;
            app.PerformPeakFindingButton.Enable = 'on';
            app.ParameterDropDown.Enable = 'on';
            app.ParameterDropDownLabel.Enable = 'on';
            waitbar(1, f, "Finished Finding");
            close(f);
            drawnow

            i = app.ParameterDropDown.ValueIndex;
            if i > length(app.parameter_labels)
                i = 1;
                app.ParameterDropDown.ValueIndex = 1;
            end

            plot_parameter(app, i, parameters_dataframe)

            surface3d(app)
            spectrum(app)


            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
            align_image_setup(app);
            % profile viewer;
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.currentRandomIndex = app.currentRandomIndex - 1;
            if app.currentRandomIndex < 1
                app.currentRandomIndex = length(app.randomCoords);
            end
            app.Xcoordinate.Value = app.randomCoords(app.currentRandomIndex, 1);
            app.Ycoordinate.Value = app.randomCoords(app.currentRandomIndex, 2);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            global parameters_dataframe
            i = app.ParameterDropDown.ValueIndex;
  
            if i > length(app.parameter_labels)
                plot_data(app)
                return;
            end

            plot_parameter(app, i, parameters_dataframe);
            if strcmp(app.TabGroup.SelectedTab.Title, "Slope Suppression")
                CalculateSlopesButtonPushed(app, event);
            end

            surface3d(app);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
        end

        % Value changed function: LoadedListBox
        function LoadedListBoxValueChanged(app, event)
            global gWide parameters_dataframe
            index = app.LoadedListBox.ValueIndex;
            gWide = app.all_gWides(index);
            
            if length(app.all_parameter_dfs) >= index
                if isempty(app.all_parameter_dfs{index})
                    plot_data(app)
                    plot_signal(app)
                    app.PerformPeakFindingButton.Enable = 'on';
                    app.ParameterDropDown.Enable = 'off';
                    app.ParameterDropDownLabel.Enable = 'off';
                    return;
                end
                parameters_dataframe = app.all_parameter_dfs{index};
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'on';
                app.ParameterDropDownLabel.Enable = 'on';
            else
                plot_data(app)
                plot_signal(app)
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'off';
                app.ParameterDropDownLabel.Enable = 'off';
                return;
            end
            
            i = app.ParameterDropDown.ValueIndex;

            if i > length(app.parameter_labels)
                plot_data(app)
                plot_signal(app)
                return;
            end
            
            i = app.ParameterDropDown.ValueIndex;
            if i > length(app.parameter_labels)
                i = 1;
                app.ParameterDropDown.ValueIndex = 1;
            end

            plot_parameter(app, i, parameters_dataframe)

            title(app.DisplayAxes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(app.DisplayAxes, 'off')
            plot_signal(app)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value)
        end

        % Value changed function: ReflectionEditField
        function ReflectionEditFieldValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, "Slope Suppression")
                value = app.LowerLimitEditField.Value;
                value2 = app.UpperLimitEditField.Value;
                if value > value2
                    return
                end
                clim(app.SlopeMapAxes, [value, value2]);
            end
            
            value = app.LowerLimitEditField.Value;
            value2 = app.UpperLimitEditField.Value;
            clim(app.DisplayAxes, [value, value2]);
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, "Slope Suppression")
                value = app.LowerLimitEditField.Value;
                value2 = app.UpperLimitEditField.Value;
                if value > value2
                    return
                end
                clim(app.SlopeMapAxes, [value, value2]);
            end

            value = app.LowerLimitEditField.Value;
            value2 = app.UpperLimitEditField.Value;
            clim(app.DisplayAxes,[value, value2]);
        end

        % Value changed function: SmthDegreeSpinner
        function SmthDegreeSpinnerValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: SmthSpanSpinner
        function SmthSpanSpinnerValueChanged(app, event)
            plot_signal(app);            
        end

        % Value changed function: OfPeaksSpinner
        function OfPeaksSpinnerValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: MinDistEditField
        function MinDistEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Button down function: DisplayAxes
        function handle(app, event)
            point = app.DisplayAxes.CurrentPoint;
            if all(point(1, :) < 1)
                return;
            end
            app.Xcoordinate.Value = point(1, 1);
            app.Ycoordinate.Value = point(1, 2);
            replot_crosshair(app, point(1, 1), point(1, 2));
            plot_signal(app);
        end

        % Button pushed function: SaveCurrentParameterDataButton
        function SaveCurrentParameterDataButtonPushed(app, event)
            global gWide parameters_dataframe
            if isempty(app.all_gWides) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            [file, location] = uiputfile('data.mat');
            if location ~= 0
                index = app.ParameterDropDown.ValueIndex;
                file_path = fullfile(location, file); 
                if index > numel(app.parameter_labels)
                    data = squeeze(gWide.signal(:,:,2))';
                    % file_path = fullfile(selected_folder, "data.mat");
                    save(file_path, 'data');
                else
                    if isempty(parameters_dataframe) || isempty(app.all_parameter_dfs)
                        uialert(app.UIFigure, "Error getting parameters, have you performed peak finding?", "Error");                        
                    else
                        uialert(app.UIFigure, "File successfully saved to directory.", ...
                            "Success", 'Icon', 'success');
                        data = squeeze(parameters_dataframe(:, :, index));
                        % file_path = fullfile(selected_folder, "data.mat");
                        save(file_path, 'data');
                    end
                end
            end
        end

        % Button down function: SlopeMapAxes
        function SlopeMapAxesButtonDown(app, event)
            point = app.SlopeMapAxes.CurrentPoint;
            app.Xcoordinate.Value = point(1, 1);
            app.Ycoordinate.Value = point(1, 2);
            replot_crosshair(app, point(1, 1), point(1, 2));
            plot_signal(app);
        end

        % Cell edit callback: SlopeFileDataTable
        function SlopeFileDataTableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            plot_signal(app); 
        end

        % Button pushed function: CalculateSlopesButton
        function CalculateSlopesButtonPushed(app, event)
            items = app.FilestoUseListBox.Value;
            if isempty(items)
                uialert(app.UIFigure, "Please choose which files you want to calculate the slope between.", "Error")
                return;
            end
            parameters_idx = ismember(app.LoadedListBox.Items, items);
            all_data = app.all_parameter_dfs(parameters_idx);
            
            ref = app.LoadedListBox.Value;
            if ~ismember(items, ref)
                uialert(app.UIFigure, "Using first item in selection as zero field splitting index, " + ...
                    "if you'd like to change this please select one of the loaded items in the " + ...
                    "list of loaded files on the left panel.", "Warning", "Icon", "warning");
                ref_index = 1;
            else
                ref_index = ismember(items, ref);
                items = items(~ref_index);
            end

            zfs_vals = all_data{ref_index};
            
            index = app.ParameterDropDown.ValueIndex;
            if index > numel(app.parameter_labels)
                index = 1;
            end
            matching_value = app.ParameterDropDown.Value;
            msg = "Calculating slopes for " + items + " with ZFS " ...
                +  app.FilestoUseListBox.Value{ref_index} + " on parameter " + matching_value + newline;
            selection = uiconfirm(app.UIFigure, msg, "Confirm Slope Calculation");
            if strcmp(selection, 'Cancel')
                return;
            end
            
            test = app.all_gWides(1).signal;
            xsize = size(test, 1);
            ysize = size(test, 2);
            all_params = zeros(xsize, ysize, numel(all_data));
            for i=1:numel(all_data)
                curr_param = all_data{i};
                all_params(:, :, i) = curr_param(:, :, index);
            end

            app.slope_df = zeros(xsize, ysize, 3);
            f = waitbar(0, "Starting slopes calculation");
            for x = 1:xsize
                waitbar(x / xsize, f, "Calculating for column " + x);
                for y = 1:ysize
                    y_vals = squeeze(all_params(x, y, :));
                    % zfs = zfs_vals(y, x, 2);
                    % if isnan(zfs)
                    %     zfs = 10;
                    % end
                    y_vals(isnan(y_vals)) = 10;
                    [slope, r_squared, intercept] = calculate_slopes(y_vals, app.x_coords(parameters_idx), zfs_vals(x, y, index));
                    app.slope_df(x, y, :) = [slope, r_squared, intercept];
                end
            end
            waitbar(1, f, "Completed!");
            close(f);

            plot_index = app.PlottedValueDropDown.ValueIndex;
            data = app.slope_df(:, :, plot_index);
            if plot_index == 1 && app.DifferencefromIdealSwitch.ValueIndex == 2
                ideal_slope = 28.025 * 1e5 * 24.11;
                data = data ./ ideal_slope;
            end
            if plot_index == 2
                colormap(app.SlopeMapAxes, 'jet');
            else
                colormap(app.SlopeMapAxes, 'default');
            end
            data(data == 0) = NaN;
            cla(app.SlopeMapAxes)
            set(app.SlopeMapAxes,'color', [1 0 0]);
            im = imagesc(app.SlopeMapAxes, data, 'AlphaData', ~isnan(data)); % Display the slice
            im.HitTest = 'off';
            colorbar(app.SlopeMapAxes); % Add a colorbar to each subplot
            clim(app.SlopeMapAxes, "auto");
            lims = clim(app.SlopeMapAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.SlopeMapAxes,[1 size(data,2)])
            ylim(app.SlopeMapAxes,[1 size(data,1)])
            pbaspect(app.SlopeMapAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.SlopeMapAxes, 'on');
            title(app.SlopeMapAxes, [app.parameter_labels(index), " ", app.PlottedValueDropDown.Value]); % Add a title to each subplot
            hold(app.SlopeMapAxes, 'off')

            plot_signal(app);
        end

        % Value changed function: PlottedValueDropDown
        function PlottedValueDropDownValueChanged(app, event)
            plot_index = app.PlottedValueDropDown.ValueIndex;
            data = app.slope_df(:, :, plot_index);
            if plot_index == 1 && app.DifferencefromIdealSwitch.ValueIndex == 2
                ideal_slope = 28.025 * 1e5 * 24.11;
                data = data ./ ideal_slope;
            end
            data(data == 0) = NaN;
            cla(app.SlopeMapAxes)
            set(app.SlopeMapAxes,'color', [1 0 0]);
            im = imagesc(app.SlopeMapAxes, data, 'AlphaData', ~isnan(data)); % Display the slice
            im.HitTest = 'off';
            if plot_index == 2
                data = 1 - data;
                colormap(app.SlopeMapAxes, 'jet');
            else
                colormap(app.SlopeMapAxes, 'default');
            end
            colorbar(app.SlopeMapAxes); % Add a colorbar to each subplot
            clim(app.SlopeMapAxes, "auto");
            lims = clim(app.SlopeMapAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.SlopeMapAxes,[1 size(data,2)])
            ylim(app.SlopeMapAxes,[1 size(data,1)])
            pbaspect(app.SlopeMapAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.SlopeMapAxes, 'on');
            i = app.ParameterDropDown.ValueIndex;
            title(app.SlopeMapAxes, [app.parameter_labels(i), " ", app.PlottedValueDropDown.Value]); % Add a title to each subplot
            hold(app.SlopeMapAxes, 'off')
        end

        % Value changed function: DifferencefromIdealSwitch
        function DifferencefromIdealSwitchValueChanged(app, event)
            PlottedValueDropDownValueChanged(app, event);
        end

        % Callback function
        function ColormapEditorButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'on';
            colormapeditor(app.LaplacianAxes);
        end

        % Value changed function: Xcoordinate
        function XcoordinateValueChanged2(app, event)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app);
        end

        % Value changing function: Xcoordinate
        function XcoordinateValueChanging(app, event)
            replot_crosshair(app, event.Value, app.Ycoordinate.Value);
            plot_signal(app, event.Value, app.Ycoordinate.Value);
        end

        % Value changed function: Ycoordinate
        function YcoordinateValueChanged2(app, event)
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
            plot_signal(app)
        end

        % Value changing function: Ycoordinate
        function YcoordinateValueChanging(app, event)
            replot_crosshair(app, app.Xcoordinate.Value, event.Value);
            plot_signal(app, app.Xcoordinate.Value, event.Value)
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            plot_signal(app);
        end

        % Button pushed function: AlignImagesButton
        function AlignImagesButtonPushed(app, event)
            items = app.FilestoUseListBox.Value;
            if isempty(items)
                uialert(app.UIFigure, "Please select images to align.", "Error")
                return;
            end
            parameters_idx = ismember(app.LoadedListBox.Items, items);
            all_data = app.all_parameter_dfs(parameters_idx);
            fluorescence = app.all_gWides(parameters_idx);
            
            ref = app.LoadedListBox.Value;
            if ~ismember(items, ref)
                uialert(app.UIFigure, "Using last item in selection as reference index, " + ...
                    "if you'd like to change this please select one of the loaded items in the " + ...
                    "list of loaded files on the left panel.", "Warning", "Icon", "warning");
                ref_index = numel(items);
            else
                ref_index = ismember(items, ref);
                items = items(~ref_index);
            end

            matching_index = app.ParameterDropDown.ValueIndex;
            matching_value = app.ParameterDropDown.Value;
            msg = "Aligning image " + items + " with " ...
                +  app.FilestoUseListBox.Value{ref_index} + " on parameter " + matching_value + newline;
            selection = uiconfirm(app.UIFigure, msg, "Confirming Alignment");
            if strcmp(selection, 'Cancel')
                return;
            end

            using_fluorescence = false;
            if matching_index > numel(app.parameter_labels)
                fixed_data = fluorescence(ref_index).signal(:, :, matching_index)';
                using_fluorescence = true;
            else
                fixed_data = all_data{ref_index};
                fixed_data = fixed_data(:, :, matching_index);
            end

            for i=1:numel(all_data)
                if using_fluorescence
                    moving_data = fluorescence(i).signal(:, :, matching_index)';
                else
                    moving_data = all_data{i};
                    moving_data = moving_data(:, :, matching_index);
                end
                [tform, refframe, success] = align_images(fixed_data, moving_data, strcmp(app.DisplayAlignmentSwitch.Value, 'On'));
                if ~success
                    uialert(app.UIFigure, "Failure to match identifying features for image " + ...
                        app.FilestoUseListBox.Value{i}, "Error");
                end
                % Transform all data associated with the moving data

                listbox_val = app.FilestoUseListBox.Value{i};
                actual_idx = ismember(app.LoadedListBox.Items, listbox_val);
                actual_idx = find(actual_idx == 1);
                for j=1:numel(app.parameter_labels)
                    data = app.all_parameter_dfs{actual_idx};
                    transformed_data = transform_data(data(:, :, j), tform, refframe);
                    app.all_parameter_dfs{actual_idx}(:, :, j) = transformed_data;
                end
            end
        end

        % Button pushed function: SavePlotDataButton
        function SavePlotDataButtonPushed(app, event)
            if isempty(app.slope_df) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            [file, location] = uiputfile('data.mat');
            if location ~= 0
                file_path = fullfile(location, file); 
                uialert(app.UIFigure, "File successfully saved to directory.", ...
                    "Success", 'Icon', 'success');
                data = squeeze(app.slope_df(:, :, 1));
                save(file_path, 'data');
                figure;
                imagesc(data)
            end
        end

        % Button pushed function: PassDownSplitButton
        function PassDownSplitButtonPushed(app, event)
            global parameters_dataframe
            items = app.FilestoUseListBox.Value;
            if isempty(items)
                uialert(app.UIFigure, "Please select maps to repass.", "Error")
                return;
            end
            self_pass = isscalar(items);
            parameters_idx = ismember(app.LoadedListBox.Items, items);
            all_data = app.all_parameter_dfs(parameters_idx);
            fluorescence = app.all_gWides(parameters_idx);
            
            ref = app.LoadedListBox.Value;
            if ~ismember(items, ref)
                uialert(app.UIFigure, "Using last item in selection as reference index, " + ...
                    "if you'd like to change this please select one of the loaded items in the " + ...
                    "list of loaded files on the left panel.", "Warning", "Icon", "warning");
                ref_index = numel(items);
            else
                ref_index = ismember(items, ref);
                if ~self_pass
                    items = items(~ref_index);
                end
            end

            % matching_index = app.ParameterDropDown.ValueIndex;
            % matching_value = app.ParameterDropDown.Value;
            msg = "Rerunning " + items + " with the D map of " ...
                +  app.FilestoUseListBox.Value{ref_index} + " on all parameters." + newline;
            selection = uiconfirm(app.UIFigure, msg, "Confirming Rerun");
            if strcmp(selection, 'Cancel')
                return;
            end

            ref_data = all_data{ref_index};
            ref_data = ref_data(:, :, 1); % D_111 map
            
            ref_index = find(ref_index == 1);
            for i = 1:numel(all_data)
                f = waitbar(0, "Initializing");
                if i == ref_index && ~self_pass % Skip the one that we got the D_111 map from
                    continue;
                end
                gWide = fluorescence(i);
                data=squeeze(gWide.signal(:,:,2))';
                xsize = size(data, 2);
                ysize = size(data, 1);
                num_params = numel(app.parameter_labels) - 1;
                parameters_dataframe = zeros(ysize, xsize, num_params);
                app.PerformPeakFindingButton.Enable = 'off';
                app.ParameterDropDown.Enable = 'off';
                app.ParameterDropDownLabel.Enable = 'off';
                drawnow

                app.UseSplittingButton.Value = false;
                tolerance = app.ToleranceEditField.Value;
                num_max_peaks = app.OfPeaksSpinner.Value;
                peak_perc_threshold = app.ThresholdEditField.Value;
                diff_peak_distance = app.MinDistEditField.Value;
                smooth_span = app.SmthSpanSpinner.Value;
                smooth_degree = app.SmthDegreeSpinner.Value;
    
                data = gWide.signal;
                freq = gWide.SweepParam;
                method =  app.MethodDropDown.ValueIndex;
                seed_method = app.PeaksDropDown.ValueIndex;
                normalize = app.NormalizeCountsButton.Value;
                pdf = parameters_dataframe;
                waitbar(0.33, f, "Finding Peaks")
                parfor x = 1:xsize
                    for y = 1:ysize
                        reflection = ref_data(y, x);
                        pdf(y, x, :) = peak_find_function( ...
                            false, reflection, tolerance, x, y, data, freq, ...
                            num_max_peaks, peak_perc_threshold, diff_peak_distance, ...
                            smooth_span, smooth_degree, method, seed_method, normalize);
                    end
                end
                parameters_dataframe = pdf;
                waitbar(0.66, f, "Smoothing")
                parameters_dataframe = smoothing(parameters_dataframe, app.SmoothingDropDown.ValueIndex);
                
                if app.LockLaplacianButton.Value
                    [pdf, ~] = laplacian_smoothing(parameters_dataframe, app.lock_laplacian, false);
                else
                    [pdf, lock] = laplacian_smoothing(parameters_dataframe, 0, false);
                    app.lock_laplacian = lock;
                end
                parameters_dataframe = pdf;
                if app.SmoothingDropDown.ValueIndex == 2
                    % Repairs should use the old value if we're locked and
                    % make new ones if we're not
                    if app.LockLaplacianButton.Value
                        lock = app.lock_laplacian;
                    else
                        lock = 0;
                    end
                    [parameters_dataframe, app.lock_laplacian] = laplacian_repair(app.UseSplittingButton.Value, ...
                        lock, parameters_dataframe, data, freq, ...
                        ref_data, tolerance, num_max_peaks, peak_perc_threshold, diff_peak_distance, ...
                        smooth_span, smooth_degree, app.PeaksDropDown.ValueIndex, false);
                end
                app.CurrentLaplacianThresholdsMHzpixelLabel.Text = ['Current Laplacian Thresholds (MHz/pixel): ' sprintf('%g ', app.lock_laplacian)];

                listbox_val = app.FilestoUseListBox.Value{i};
                actual_idx = ismember(app.LoadedListBox.Items, listbox_val);
                actual_idx = find(actual_idx == 1);

                app.all_parameter_dfs{actual_idx} = parameters_dataframe;
                app.all_reflection_dfs{actual_idx} = ref_data;
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'on';
                app.ParameterDropDownLabel.Enable = 'on';
                waitbar(1, f, "Completed")
                close(f)
                drawnow

                surface3d(app)
                spectrum(app)
    
                plot_signal(app)
                align_image_setup(app);

                j = app.ParameterDropDown.ValueIndex;
                if j > length(app.parameter_labels)
                    j = 1;
                    app.ParameterDropDown.ValueIndex = 1;
                end
                plot_parameter(app, j, parameters_dataframe)
            end
        end

        % Value changed function: HighlightErrorsButton
        function HighlightErrorsButtonValueChanged(app, event)
            % value = app.HighlightErrorsButton.Value;
            ParameterDropDownValueChanged(app, event);
        end

        % Value changed function: UseSplittingButton
        function UseSplittingButtonValueChanged(app, event)
            value = app.UseSplittingButton.Value;
            if value
                app.ReflectionEditFieldLabel.Text = "Splitting";
            else
                app.ReflectionEditFieldLabel.Text = "Reflection";
            end

            plot_signal(app)
        end

        % Value changed function: PeaksDropDown
        function PeaksDropDownValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: LockLaplacianButton
        function LockLaplacianButtonValueChanged(app, event)
            % Recalculating with standard thresholds requires a complete
            % refind in order to allow for laplacian repairs
            % Thus, locking/unlocking shouldn't change anything about the
            % actual display itself but rather allow one to refit
        end

        % Value changed function: LineCutDropDown
        function LineCutDropDownValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: NormalizeCountsButton
        function NormalizeCountsButtonValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: PreviousSettingsButton
        function PreviousSettingsButtonValueChanged(app, event)
            % value = app.PreviousSettingsButton.Value;
            plot_signal(app);
            replot_crosshair(app, app.Xcoordinate.Value, app.Ycoordinate.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = ',Peak Finding Debugger';
            app.ShowPeaksSwitchLabel = uilabel(app.LeftPanel);
            app.ShowPeaksSwitchLabel.Text = 'Show Peaks';
            app.LoadedListBox = uilistbox(app.LeftPanel);
            app.LoadedListBox.Items = {};
            app.LoadedListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedListBoxValueChanged, true);
            app.LoadedListBox.Value = {};
            app.Xcoordinate = uispinner(app.LeftPanel);
            app.Xcoordinate.ValueChangingFcn = createCallbackFcn(app, @XcoordinateValueChanging, true);
            app.Xcoordinate.Limits = [1 512];
            app.Xcoordinate.RoundFractionalValues = 'on';
            app.Xcoordinate.ValueChangedFcn = createCallbackFcn(app, @XcoordinateValueChanged2, true);
            app.Xcoordinate.Value = 1;
            app.Ycoordinate = uispinner(app.LeftPanel);
            app.Ycoordinate.ValueChangingFcn = createCallbackFcn(app, @YcoordinateValueChanging, true);
            app.Ycoordinate.Limits = [1 512];
            app.Ycoordinate.RoundFractionalValues = 'on';
            app.Ycoordinate.ValueChangedFcn = createCallbackFcn(app, @YcoordinateValueChanged2, true);
            app.Ycoordinate.Value = 1;
            app.ReflectionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ReflectionEditField.Limits = [0 Inf];
            app.ReflectionEditField.ValueChangedFcn = createCallbackFcn(app, @ReflectionEditFieldValueChanged, true);
            app.ReflectionEditField.Value = 3100000000;
            app.ToleranceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 100000000;
            app.MinDistEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinDistEditField.Limits = [0 Inf];
            app.MinDistEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistEditFieldValueChanged, true);
            app.MinDistEditField.Value = 10000000;
            app.ThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThresholdEditField.Limits = [1 100];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 5;
            app.OfPeaksSpinner = uispinner(app.LeftPanel);
            app.OfPeaksSpinner.Limits = [1 Inf];
            app.OfPeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @OfPeaksSpinnerValueChanged, true);
            app.OfPeaksSpinner.Value = 2;
            app.SmthSpanSpinner = uispinner(app.LeftPanel);
            app.SmthSpanSpinner.Step = 2;
            app.SmthSpanSpinner.Limits = [1 Inf];
            app.SmthSpanSpinner.ValueChangedFcn = createCallbackFcn(app, @SmthSpanSpinnerValueChanged, true);
            app.SmthSpanSpinner.Value = 41;
            app.SmthDegreeSpinner = uispinner(app.LeftPanel);
            app.SmthDegreeSpinner.Limits = [1 Inf];
            app.SmthDegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @SmthDegreeSpinnerValueChanged, true);
            app.SmthDegreeSpinner.Value = 7;
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'Tallest, fail=average', 'Prominent', 'Closest', 'Avg. Tallest 2', 'Avg. Prom 2', 'Tallest'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Tallest, fail=average';
            app.SmoothingDropDown = uidropdown(app.LeftPanel);
            app.SmoothingDropDown.Items = {'None', 'Laplacian Fix', 'Average 4', 'Gaussian', 'Blur Laplacian'};
            app.SmoothingDropDown.Value = 'Laplacian Fix';
            app.PeaksDropDownLabel = uilabel(app.LeftPanel);
            app.PeaksDropDownLabel.Text = '111 Peaks';
            app.PeaksDropDown = uidropdown(app.LeftPanel);
            app.PeaksDropDown.Items = {'Tallest Single', 'Tallest Average', 'Tallest Outermost', 'Leftmost', 'Rightmost', 'Innermost (smallest split)', 'Closest to Guess'};
            app.PeaksDropDown.ValueChangedFcn = createCallbackFcn(app, @PeaksDropDownValueChanged, true);
            app.PeaksDropDown.Value = 'Tallest Single';
            app.PerformPeakFindingButton = uibutton(app.LeftPanel, 'push');
            app.PerformPeakFindingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformPeakFindingButtonPushed, true);
            app.PerformPeakFindingButton.Text = 'Perform Peak Finding';
            app.ParameterDropDown = uidropdown(app.LeftPanel);
            app.ParameterDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', 'Tries', 'Left Peak', 'Right Peak', 'Laplacian', 'Default'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDown.Value = 'Default';
            app.LowerLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.UpperLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveCurrentParameterDataButton = uibutton(app.LeftPanel, 'push');
            app.SaveCurrentParameterDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentParameterDataButtonPushed, true);
            app.SaveCurrentParameterDataButton.Text = 'Save Current Parameter Data';
            app.ShowRawRedLabel = uilabel(app.LeftPanel);
            app.ShowRawRedLabel.Text = 'Show Raw (Red)';
            app.ReflectionEditFieldLabel = uilabel(app.LeftPanel);
            app.ReflectionEditFieldLabel.Text = 'Reflection';
            app.ToleranceEditFieldLabel = uilabel(app.LeftPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.LowerLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.UpperLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.MinDistEditFieldLabel = uilabel(app.LeftPanel);
            app.MinDistEditFieldLabel.Text = 'Min. Dist.';
            app.ThresholdLabel = uilabel(app.LeftPanel);
            app.ThresholdLabel.Text = 'Threshold %';
            app.OfPeaksSpinnerLabel = uilabel(app.LeftPanel);
            app.OfPeaksSpinnerLabel.Text = '# Of Peaks';
            app.SmthSpanLabel = uilabel(app.LeftPanel);
            app.SmthSpanLabel.Text = 'Smth Span';
            app.SmthDegreeSpinnerLabel = uilabel(app.LeftPanel);
            app.SmthDegreeSpinnerLabel.Text = 'Smth Degree';
            app.XcoordinateSpinnerLabel = uilabel(app.LeftPanel);
            app.XcoordinateSpinnerLabel.Text = 'X coordinate';
            app.YcoordinateLabel = uilabel(app.LeftPanel);
            app.YcoordinateLabel.Text = 'Y coordinate';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.SmoothingDropDownLabel = uilabel(app.LeftPanel);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.ParameterDropDownLabel = uilabel(app.LeftPanel);
            app.ParameterDropDownLabel.Enable = 'off';
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ShowRawSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowRawSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowRawSwitchValueChanged, true);
            app.ShowPeaksSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowPeaksSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowPeaksSwitchValueChanged, true);
            app.ShowPeaksSwitch.Value = 'On';
            app.NormalizeCountsButton = uibutton(app.LeftPanel, 'state');
            app.NormalizeCountsButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeCountsButtonValueChanged, true);
            app.NormalizeCountsButton.Text = 'Normalize Counts';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PeakFindingTab = uitab(app.TabGroup);
            app.PeakFindingTab.Title = 'Peak Finding';
            app.GridLayout2 = uigridlayout(app.PeakFindingTab);
            app.DisplayAxes = uiaxes(app.GridLayout2);
            xlabel(app.DisplayAxes, 'X')
            ylabel(app.DisplayAxes, 'Y')
            zlabel(app.DisplayAxes, 'Z')
            app.DisplayAxes.ButtonDownFcn = createCallbackFcn(app, @handle, true);
            app.SpectrumAxes = uiaxes(app.GridLayout2);
            title(app.SpectrumAxes, 'Signal')
            xlabel(app.SpectrumAxes, 'Frequency')
            ylabel(app.SpectrumAxes, 'Response')
            zlabel(app.SpectrumAxes, 'Z')
            app.LineCutDropDown = uidropdown(app.GridLayout2);
            app.LineCutDropDown.Items = {'Pixel', 'Vertical', 'Horizontal'};
            app.LineCutDropDown.ValueChangedFcn = createCallbackFcn(app, @LineCutDropDownValueChanged, true);
            app.LineCutDropDown.Value = 'Pixel';
            app.CurrentLaplacianThresholdsMHzpixelLabel = uilabel(app.GridLayout2);
            app.CurrentLaplacianThresholdsMHzpixelLabel.Text = 'Current Laplacian Thresholds (MHz/pixel): ';
            app.HighlightErrorsButton = uibutton(app.GridLayout2, 'state');
            app.HighlightErrorsButton.ValueChangedFcn = createCallbackFcn(app, @HighlightErrorsButtonValueChanged, true);
            app.HighlightErrorsButton.Text = 'Highlight Errors';
            app.LockLaplacianButton = uibutton(app.GridLayout2, 'state');
            app.LockLaplacianButton.ValueChangedFcn = createCallbackFcn(app, @LockLaplacianButtonValueChanged, true);
            app.LockLaplacianButton.Text = 'Lock Laplacian Thresholds';
            app.UseSplittingButton = uibutton(app.GridLayout2, 'state');
            app.UseSplittingButton.ValueChangedFcn = createCallbackFcn(app, @UseSplittingButtonValueChanged, true);
            app.UseSplittingButton.Text = 'Use Splitting (E map)';
            app.ofChunksEditFieldLabel = uilabel(app.GridLayout2);
            app.ofChunksEditFieldLabel.Text = '# of Chunks';
            app.ofChunksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ofChunksEditField.Limits = [1 256];
            app.ofChunksEditField.RoundFractionalValues = 'on';
            app.ofChunksEditField.Value = 3;
            app.GenChunkButton = uibutton(app.GridLayout2, 'push');
            app.GenChunkButton.ButtonPushedFcn = createCallbackFcn(app, @GenChunkButtonPushed, true);
            app.GenChunkButton.Text = 'Gen. Chunk';
            app.LeftButton = uibutton(app.GridLayout2, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<<';
            app.RightButton = uibutton(app.GridLayout2, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>>';
            app.PreviousSettingsButton = uibutton(app.GridLayout2, 'state');
            app.PreviousSettingsButton.ValueChangedFcn = createCallbackFcn(app, @PreviousSettingsButtonValueChanged, true);
            app.PreviousSettingsButton.Text = 'Previous Settings';
            app.PixelDetailsTab = uitab(app.TabGroup);
            app.PixelDetailsTab.Title = 'Pixel Details';
            app.PixelValueAxes = uiaxes(app.PixelDetailsTab);
            title(app.PixelValueAxes, 'Change in Pixel Value')
            xlabel(app.PixelValueAxes, 'X')
            ylabel(app.PixelValueAxes, 'Y')
            zlabel(app.PixelValueAxes, 'Z')
            app.PixelValueTable = uitable(app.PixelDetailsTab);
            app.PixelValueTable.ColumnName = {'Parameter'; 'Value'};
            app.PixelValueTable.RowName = {};
            app.FileDataTable = uitable(app.PixelDetailsTab);
            app.FileDataTable.ColumnName = {'File'; 'Value'; 'X'};
            app.FileDataTable.RowName = {};
            app.FileDataTable.ColumnEditable = [false false false];
            app.SlopeSuppressionTab = uitab(app.TabGroup);
            app.SlopeSuppressionTab.Title = 'Slope Suppression';
            app.SlopeMapAxes = uiaxes(app.SlopeSuppressionTab);
            xlabel(app.SlopeMapAxes, 'X')
            ylabel(app.SlopeMapAxes, 'Y')
            zlabel(app.SlopeMapAxes, 'Z')
            app.SlopeMapAxes.ButtonDownFcn = createCallbackFcn(app, @SlopeMapAxesButtonDown, true);
            app.SlopeAxes = uiaxes(app.SlopeSuppressionTab);
            title(app.SlopeAxes, 'Slope')
            xlabel(app.SlopeAxes, 'X')
            ylabel(app.SlopeAxes, 'Value')
            zlabel(app.SlopeAxes, 'Z')
            app.SlopeFileDataTable = uitable(app.SlopeSuppressionTab);
            app.SlopeFileDataTable.ColumnName = {'File'; 'Value'; 'X'};
            app.SlopeFileDataTable.RowName = {};
            app.SlopeFileDataTable.ColumnEditable = [false false true];
            app.SlopeFileDataTable.CellEditCallback = createCallbackFcn(app, @SlopeFileDataTableCellEdit, true);
            app.PlottedValueDropDownLabel = uilabel(app.SlopeSuppressionTab);
            app.PlottedValueDropDownLabel.Text = 'Plotted Value';
            app.PlottedValueDropDown = uidropdown(app.SlopeSuppressionTab);
            app.PlottedValueDropDown.Items = {'Slope', 'R-squared', 'Intercept'};
            app.PlottedValueDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottedValueDropDownValueChanged, true);
            app.PlottedValueDropDown.Value = 'Slope';
            app.FilestoUseListBox = uilistbox(app.SlopeSuppressionTab);
            app.FilestoUseListBox.Items = {};
            app.FilestoUseListBox.Multiselect = 'on';
            app.FilestoUseListBox.Value = {};
            app.AlignImagesButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.AlignImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AlignImagesButtonPushed, true);
            app.AlignImagesButton.Tooltip = {'Select files to align in the above list box, then click this button to align them with one another,'};
            app.AlignImagesButton.Text = 'Align Images';
            app.FilestoUseListBoxLabel = uilabel(app.SlopeSuppressionTab);
            app.FilestoUseListBoxLabel.Text = 'Files to Use';
            app.SavePlotDataButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.SavePlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotDataButtonPushed, true);
            app.SavePlotDataButton.Text = 'Save Plot Data';
            app.CalculateSlopesButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.CalculateSlopesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopesButtonPushed, true);
            app.CalculateSlopesButton.Text = 'Calculate Slopes';
            app.DifferencefromIdealSwitchLabel = uilabel(app.SlopeSuppressionTab);
            app.DifferencefromIdealSwitchLabel.Text = 'Difference from Ideal';
            app.DifferencefromIdealSwitch = uiswitch(app.SlopeSuppressionTab, 'slider');
            app.DifferencefromIdealSwitch.ValueChangedFcn = createCallbackFcn(app, @DifferencefromIdealSwitchValueChanged, true);
            app.DifferencefromIdealSwitch.Value = 'On';
            app.SlopeLabel = uilabel(app.SlopeSuppressionTab);
            app.SlopeLabel.Text = 'Slope';
            app.InterceptLabel = uilabel(app.SlopeSuppressionTab);
            app.InterceptLabel.Text = 'Intercept:';
            app.DisplayAlignmentSwitchLabel = uilabel(app.SlopeSuppressionTab);
            app.DisplayAlignmentSwitchLabel.Text = 'Display Alignment';
            app.DisplayAlignmentSwitch = uiswitch(app.SlopeSuppressionTab, 'slider');
            app.PassDownSplitButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.PassDownSplitButton.ButtonPushedFcn = createCallbackFcn(app, @PassDownSplitButtonPushed, true);
            app.PassDownSplitButton.Text = 'Pass Down Split';
            app.FunPlotsTab = uitab(app.TabGroup);
            app.FunPlotsTab.Title = 'Fun Plots';
            app.Axes3D = uiaxes(app.FunPlotsTab);
            title(app.Axes3D, ',')
            xlabel(app.Axes3D, 'X')
            ylabel(app.Axes3D, 'Y')
            zlabel(app.Axes3D, 'Z')
            app.HorizontalSpectrumAxes = uiaxes(app.FunPlotsTab);
            title(app.HorizontalSpectrumAxes, 'Horizontal Signal')
            xlabel(app.HorizontalSpectrumAxes, 'Peak Location')
            ylabel(app.HorizontalSpectrumAxes, 'Row')
            zlabel(app.HorizontalSpectrumAxes, 'Z')
            app.VerticalSpectrumAxes = uiaxes(app.FunPlotsTab);
            title(app.VerticalSpectrumAxes, 'Vertical Signal')
            xlabel(app.VerticalSpectrumAxes, 'Peak Location')
            ylabel(app.VerticalSpectrumAxes, 'Column')
            zlabel(app.VerticalSpectrumAxes, 'Z')
            app.LaplacianAxes = uiaxes(app.FunPlotsTab);
            title(app.LaplacianAxes, ',')
            xlabel(app.LaplacianAxes, 'X')
            ylabel(app.LaplacianAxes, 'Y')
            zlabel(app.LaplacianAxes, 'Z')
            app.ColormapEditorButton = uibutton(app.FunPlotsTab, 'push');
            app.ColormapEditorButton.Text = 'Colormap Editor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = global_peaks_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end